classdef CONVET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextoTextArea       matlab.ui.control.TextArea
        TextoTextAreaLabel  matlab.ui.control.Label
        ConviertetuvozenTextoButtonGroup  matlab.ui.container.ButtonGroup
        svdButton           matlab.ui.control.StateButton
        EliminarButton      matlab.ui.control.ToggleButton
        ContinuarButton     matlab.ui.control.ToggleButton
        GrabarButton        matlab.ui.control.ToggleButton
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model = load("model.mat");
        path = "test_audios/"

    end
    
    methods (Access = private)
        
        function audio_vector = transform_audio(name,fs,extra_table,coeff,mu,sigma)
            possible_fs = [768000, 384000, 192000, 96000, 48000, 24000, 12000, 705600, 352800, 176400, 88200, 44100, 22050, 11025, 32000, 16000, 8000];
            [audio, fs_x] = audioread(strcat("audios/",name));
                if(size(audio,2) > 1)
                    audio = mean(audio,2);
                end
                [~, index] = min(abs(possible_fs - fs_x));
                audio = audioresample(audio,InputRate=possible_fs(index),OutputRate=fs);
                mfccs = mfcc(audio,fs);
                mfccs = reshape(mfccs,[],1);
                centroid = spectralCentroid(audio, fs);
                centroid(isnan(centroid)) = 0;
                spread = spectralSpread(audio,fs);
                spread(isnan(spread)) = 0;
                kurtosis = spectralKurtosis(audio,fs);
                kurtosis(isnan(kurtosis)) = 0;
                % Concatenar las características en un solo vector
                mfcc_new = zeros([1,extra_table.max_mfccs]);
                mfcc_new(1:length(mfccs)) = mfccs;
                centroid_new = zeros([1,extra_table.max_centroid]);
                centroid_new(1:length(centroid)) = centroid;
                spread_new = zeros([1,extra_table.max_spread]);
                spread_new(1:length(spread)) = spread;
                kurtosis_new = zeros([1,extra_table.max_kurtosis]);
                kurtosis_new(1:length(kurtosis)) = kurtosis;
                features = horzcat(mfcc_new,centroid_new,spread_new,kurtosis_new);
                featuresNormalized = (features - mu) ./ sigma;
                audio_vector = featuresNormalized * coeff;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: svdButton
        function svdButtonValueChanged(app, event)
            app.TextoTextArea.Placeholder = string(app.svdButton.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voz')
            app.ConviertetuvozenTextoButtonGroup = uibuttongroup(app.UIFigure);
            app.ConviertetuvozenTextoButtonGroup.Title = 'Convierte tu voz en Texto';
            app.GrabarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.GrabarButton.Text = 'Grabar';
            app.GrabarButton.Value = true;
            app.ContinuarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.ContinuarButton.Text = 'Continuar';
            app.EliminarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.EliminarButton.Text = 'Eliminar';
            app.svdButton = uibutton(app.ConviertetuvozenTextoButtonGroup, 'state');
            app.svdButton.ValueChangedFcn = createCallbackFcn(app, @svdButtonValueChanged, true);
            app.svdButton.Text = 'svd';
            app.TextoTextAreaLabel = uilabel(app.UIFigure);
            app.TextoTextAreaLabel.Text = 'Texto:';
            app.TextoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end